classdef DownburstModelsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectModelPanel                matlab.ui.container.Panel
        Vicroy1992Button                matlab.ui.control.Button
        OsegueraBowles1988Button        matlab.ui.control.Button
        XhelajBurlandoSolari2020Button  matlab.ui.control.Button
        Woodetal2001Button              matlab.ui.control.Button
        Verhoff1963Button               matlab.ui.control.Button
        MoeiniRomanic2023Button         matlab.ui.control.Button
        HolmesOliver2000Button          matlab.ui.control.Button
        Lietal2012Button                matlab.ui.control.Button
        ChayAlbermaniWilson2006Button   matlab.ui.control.Button
        AbdElaalMillsMa2013Button       matlab.ui.control.Button
        DownburstModelsSelectionMenuLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        OB % OB model app
        OBV % OBV model app
        OBVAbdElaal % OBV: Abd Elaal model app
        OBVChay % OBV: Chay model app
        OBVLi % OBV: Li model app
        HolmesOliver % Holmes & Oliver model app
        MoeiniRomanic % Moeini & Romanic model app
        Verhoff % Verhoff model app
        Wood % Wood model app
        Xhelaj % Xhelaj model app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OsegueraBowles1988Button
        function OsegueraBowles1988ButtonPushed(app, event)
            % Disable opening multiple windows
            app.OsegueraBowles1988Button.Enable = 'off';

            % Open OB model app
            app.OB = OB(app);
        end

        % Button pushed function: Vicroy1992Button
        function Vicroy1992ButtonPushed(app, event)
            % Disable opening multiple windows
            app.Vicroy1992Button.Enable = 'off';

            % Open OBV model app
            app.OBV = OBV(app);
        end

        % Button pushed function: AbdElaalMillsMa2013Button
        function AbdElaalMillsMa2013ButtonPushed(app, event)
            % Disable opening multiple windows
            app.AbdElaalMillsMa2013Button.Enable = 'off';

            % Open OBV model app
            app.OBVAbdElaal = OBV_AbdElaal(app);
        end

        % Button pushed function: ChayAlbermaniWilson2006Button
        function ChayAlbermaniWilson2006ButtonPushed(app, event)
            % Disable opening multiple windows
            app.ChayAlbermaniWilson2006Button.Enable = 'off';

            % Open OBV: Chay model app
            app.OBVChay = OBV_Chay(app);
        end

        % Button pushed function: Lietal2012Button
        function Lietal2012ButtonPushed(app, event)
            % Disable opening multiple windows
            app.Lietal2012Button.Enable = 'off';

            % Open OBV: Li model app
            app.OBVLi = OBV_Li(app);  
        end

        % Button pushed function: HolmesOliver2000Button
        function HolmesOliver2000ButtonPushed(app, event)
            % Disable opening multiple windows
            app.HolmesOliver2000Button.Enable = 'off';

            % Open Holmes & Oliver model app
            app.HolmesOliver = HolmesOliver(app);  
        end

        % Button pushed function: MoeiniRomanic2023Button
        function MoeiniRomanic2023ButtonPushed(app, event)
            % Disable opening multiple windows
            app.MoeiniRomanic2023Button.Enable = 'off';

            % Open Holmes & Oliver model app
            app.MoeiniRomanic = MoeiniRomanic(app);  
        end

        % Button pushed function: Verhoff1963Button
        function Verhoff1963ButtonPushed(app, event)
            % Disable opening multiple windows
            app.Verhoff1963Button.Enable = 'off';

            % Open Verhoff model app
            app.Verhoff = Jet_Verhoff(app);  
        end

        % Button pushed function: Woodetal2001Button
        function Woodetal2001ButtonPushed(app, event)
            % Disable opening multiple windows
            app.Woodetal2001Button.Enable = 'off';

            % Open Wood model app
            app.Wood = Jet_Wood(app); 
        end

        % Button pushed function: XhelajBurlandoSolari2020Button
        function XhelajBurlandoSolari2020ButtonPushed(app, event)
            % Disable opening multiple windows
            app.XhelajBurlandoSolari2020Button.Enable = 'off';

            % Open Wood model app
            app.Xhelaj = Xhelaj(app); 
        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            % Close all windows when selection menu is closed
            delete(app.OB)
            delete(app.OBV)
            delete(app.OBVAbdElaal)
            delete(app.OBVChay)
            delete(app.OBVLi)
            delete(app.HolmesOliver)
            delete(app.MoeiniRomanic)
            delete(app.Verhoff)
            delete(app.Wood)
            delete(app.Xhelaj)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.DownburstModelsSelectionMenuLabel = uilabel(app.UIFigure);
            app.DownburstModelsSelectionMenuLabel.Text = 'Downburst Models Selection Menu';
            app.SelectModelPanel = uipanel(app.UIFigure);
            app.SelectModelPanel.Title = 'Select Model:';
            app.AbdElaalMillsMa2013Button = uibutton(app.SelectModelPanel, 'push');
            app.AbdElaalMillsMa2013Button.ButtonPushedFcn = createCallbackFcn(app, @AbdElaalMillsMa2013ButtonPushed, true);
            app.AbdElaalMillsMa2013Button.Tooltip = {''};
            app.AbdElaalMillsMa2013Button.Text = 'Abd-Elaal, Mills, & Ma (2013)';
            app.ChayAlbermaniWilson2006Button = uibutton(app.SelectModelPanel, 'push');
            app.ChayAlbermaniWilson2006Button.ButtonPushedFcn = createCallbackFcn(app, @ChayAlbermaniWilson2006ButtonPushed, true);
            app.ChayAlbermaniWilson2006Button.Text = 'Chay, Albermani, & Wilson (2006)';
            app.Lietal2012Button = uibutton(app.SelectModelPanel, 'push');
            app.Lietal2012Button.ButtonPushedFcn = createCallbackFcn(app, @Lietal2012ButtonPushed, true);
            app.Lietal2012Button.Text = 'Li et al. (2012)';
            app.HolmesOliver2000Button = uibutton(app.SelectModelPanel, 'push');
            app.HolmesOliver2000Button.ButtonPushedFcn = createCallbackFcn(app, @HolmesOliver2000ButtonPushed, true);
            app.HolmesOliver2000Button.Text = 'Holmes & Oliver (2000)';
            app.MoeiniRomanic2023Button = uibutton(app.SelectModelPanel, 'push');
            app.MoeiniRomanic2023Button.ButtonPushedFcn = createCallbackFcn(app, @MoeiniRomanic2023ButtonPushed, true);
            app.MoeiniRomanic2023Button.Text = 'Moeini & Romanic (2023)';
            app.Verhoff1963Button = uibutton(app.SelectModelPanel, 'push');
            app.Verhoff1963Button.ButtonPushedFcn = createCallbackFcn(app, @Verhoff1963ButtonPushed, true);
            app.Verhoff1963Button.Text = 'Verhoff (1963)';
            app.Woodetal2001Button = uibutton(app.SelectModelPanel, 'push');
            app.Woodetal2001Button.ButtonPushedFcn = createCallbackFcn(app, @Woodetal2001ButtonPushed, true);
            app.Woodetal2001Button.Text = 'Wood et al. (2001)';
            app.XhelajBurlandoSolari2020Button = uibutton(app.SelectModelPanel, 'push');
            app.XhelajBurlandoSolari2020Button.ButtonPushedFcn = createCallbackFcn(app, @XhelajBurlandoSolari2020ButtonPushed, true);
            app.XhelajBurlandoSolari2020Button.Text = 'Xhelaj, Burlando, & Solari (2020)';
            app.OsegueraBowles1988Button = uibutton(app.SelectModelPanel, 'push');
            app.OsegueraBowles1988Button.ButtonPushedFcn = createCallbackFcn(app, @OsegueraBowles1988ButtonPushed, true);
            app.OsegueraBowles1988Button.Text = 'Oseguera & Bowles (1988)';
            app.Vicroy1992Button = uibutton(app.SelectModelPanel, 'push');
            app.Vicroy1992Button.ButtonPushedFcn = createCallbackFcn(app, @Vicroy1992ButtonPushed, true);
            app.Vicroy1992Button.Text = 'Vicroy (1992)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DownburstModelsGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end