classdef OBV_AbdElaal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        Panel_3                         matlab.ui.container.Panel
        FixedParametersCheckBox         matlab.ui.control.CheckBox
        PlottingOptionsPanel            matlab.ui.container.Panel
        PlotverticalvelocityCheckBox    matlab.ui.control.CheckBox
        PlothorizontalvelocityCheckBox  matlab.ui.control.CheckBox
        PlotmagnitudeCheckBox           matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        zField                          matlab.ui.control.NumericEditField
        zSlider                         matlab.ui.control.Slider
        zmLabel                         matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        rSlider                         matlab.ui.control.Slider
        rField                          matlab.ui.control.NumericEditField
        zmLabel_2                       matlab.ui.control.Label
        ExportDataTableButton_2         matlab.ui.control.Button
        ExportPlotButton_2              matlab.ui.control.Button
        VerticalProfileModelParametersPanel  matlab.ui.container.Panel
        sfField_2                       matlab.ui.control.NumericEditField
        sfSlider_2                      matlab.ui.control.Slider
        ScalingfactorLabel_2            matlab.ui.control.Label
        zmField_2                       matlab.ui.control.NumericEditField
        zmSlider_2                      matlab.ui.control.Slider
        HeightofmaximumhorizontalvelocitymLabel_2  matlab.ui.control.Label
        JetDiameterField_2              matlab.ui.control.NumericEditField
        JetDiameterSlider_2             matlab.ui.control.Slider
        JetDiameterkmLabel_2            matlab.ui.control.Label
        ExportDataTableButton           matlab.ui.control.Button
        ExportPlotButton                matlab.ui.control.Button
        RadialProfileModelParametersPanel  matlab.ui.container.Panel
        zmField                         matlab.ui.control.NumericEditField
        zmSlider                        matlab.ui.control.Slider
        HeightofmaximumhorizontalvelocitymLabel  matlab.ui.control.Label
        sfField                         matlab.ui.control.NumericEditField
        sfSlider                        matlab.ui.control.Slider
        ScalingfactorLabel              matlab.ui.control.Label
        JetDiameterField                matlab.ui.control.NumericEditField
        JetDiameterSlider               matlab.ui.control.Slider
        JetDiameterkmLabel              matlab.ui.control.Label
        AbdElaalMillsMa2013Label        matlab.ui.control.Label
        VerticalAxes                    matlab.ui.control.UIAxes
        HorizontalAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)
        
        function u_r = compute_ur(app, r, sf, ur_1, ur_2)
            % Horizontal downburst velocity for radial profile
            u_r = (sf .* r)./2 .* ur_1 .* ur_2;
        end
        
        function w_r = compute_wr(app, sf, wr_1, wr_2)
            % Vertical downburst velocity for radial profile
            w_r = -sf .* wr_1 .* wr_2;
        end

        function u = compute_u(app, r, sf, ur_1, ur_2, wr_1, wr_2)
            % Horizontal downburst velocity for radial profile
            u_r = (sf .* r)./2 .* ur_1 .* ur_2;

            % Vertical downburst velocity for radial profile
            w_r = -sf .* wr_1 .* wr_2;

            % Downburst velocity magnitude for radial profile
            u = sqrt(u_r.^2 + w_r.^2);
        end

        function u_z = compute_uz(app, r, sf, uz_1, uz_2)
            % Horizontal downburst velocity for vertical profile
            u_z = (sf .* r)./2 .* uz_1 .* uz_2;
        end

        function w_z = compute_wz(app, sf, wz_1, wz_2)
            % Vertical downburst velocity for vertical profile
            w_z = -sf .* wz_1 .* wz_2;
        end

        function w = compute_w(app, r, sf, uz_1, uz_2, wz_1, wz_2)
            % Horizontal downburst velocity for vertical profile
            u_z = (sf .* r)./2 .* uz_1 .* uz_2;

            % Vertical downburst velocity for vertical profile
            w_z = -sf .* wz_1 .* wz_2;

            % Downburst velocity magnitude for vertical profile
            w = sqrt(u_z.^2 + w_z.^2);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller3)
            % Store selection menu app object
            app.CallingApp = caller3;
            
            % Set up plotting options menu
            app.PlotmagnitudeCheckBox.Value = 1;
            app.PlothorizontalvelocityCheckBox.Value = 1;
            app.PlotverticalvelocityCheckBox.Value = 1;
            app.FixedParametersCheckBox.Value = 1;            

            % Define parameters for radial profile
            r = 0:0.1:10000;
            D = app.JetDiameterField.Value * 1000;
            z = app.zField.Value;
            z_max = app.zmField.Value; 
            rm = 2.01*D - (z/0.0078)*exp(-((1-(z/(0.0078*D))^(-0.068))/0.068));
            zm = z_max*((1.1+7.0*(log(r./D)-0.2).^2)/(r./D));
            de = 2.1;
            gam = 0.35;
            eps = 0.06;
            kap = 0.18;
            ch = 1;
            c1 = -4.1;
            c2 = 1.1;
            ps_r = (de*r.^2./rm^2).^gam;
            sf = app.sfField.Value;

            % Individual parts of the functions
            ur_1 = exp(-(2*gam - (de.*r.^2./rm^2).^gam).^2) + eps .* exp(-kap.*(r.^2./rm^2));
            ur_2 = (z/D)^0.1 * exp(c1*(z/D)^1.1);
            wr_1 = (1 + 2.*gam.*ps_r.*(2*gam-ps_r)) .* exp(-(2*gam - ps_r).^2) + eps .* exp(-kap.*(r.^2./rm^2).^ch) .* (1 - kap.*ch.*(r./rm).^(2*ch));
            wr_2 = (D/-4.51) * (exp(-4.51*(z/D).^1.1)-1);

            % Plot radial profile horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, r, sf, ur_1, ur_2, wr_1, wr_2), 'k', r, compute_ur(app, r, sf, ur_1, ur_2), 'r', r, compute_wr(app, sf, wr_1, wr_2), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');            

%             % Adjust axis for radial profile
%             r_lim = 2 * r_max;
%             xlim(app.HorizontalAxes, [0, (r_lim+2000)]);

            % Define parameters for vertical profile
            z = 0:0.1:1000;
            D = app.JetDiameterField_2.Value * 1000;
            r = app.rField.Value * 1000;
            z_max = app.zmField_2.Value; 
            rm = 2.01*D - (z./0.0078).*exp(-((1-(z./(0.0078*D)).^(-0.068))/0.068));
            zm = z_max*((1.1+7.0*(log(r/D)-0.2)^2)/(r/D));
            ps_z = (2*r.^2./D^2).^0.35;
            gam = -227.69.*(z./D).^3+74.553.*(z./D).^2-5.0359.*(z./D)+0.4985;
            de = 2.1;
            eps = 25.466.*(z./D).^2-1.9908.*(z./D)+0.1593;
            kap = 12.938.*(z./D).^2+2.8563.*(z./D)+0.3019;
            ch = 125.38.*(z./D).^3-46.849.*(z./D).^2+0.9688.*(z./D)+0.9932;
            c1 = -0.16;
            c2 = 1.19;
            sf = app.sfField_2.Value;

            % Individual parts of the functions
            uz_1 = exp(-(0.7 - (2.*r.^2./D^2).^0.35).^2) + 0.18*exp(-0.06.*(r./D).^2);
            uz_2 = (z./zm).^(c2-1) .* exp(c1.*(z./zm).^c2);
            wz_1 = (1 + 0.7*ps_z*(0.7-ps_z)) * exp(-(0.7 - ps_z)^2) + 0.18*exp(-0.06*(r/D)^2) .* (1 - kap.*ch.*((r/D)^2));
            wz_2 = (zm/(c1*c2)) * (exp(c1.*(z./zm).^c2)-1);

            % Plot vertical profile horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_w(app, r, sf, uz_1, uz_2, wz_1, wz_2), z, 'k', compute_uz(app, r, sf, uz_1, uz_2), z, 'r', compute_wz(app, sf, wz_1, wz_2), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');
        end

        % Value changed function: JetDiameterField, sfField, zField, 
        % ...and 1 other component
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:0.1:10000;
            D = app.JetDiameterField.Value * 1000;
            z = app.zField.Value;
            z_max = app.zmField.Value; 
            rm = 2.01*D - (z/0.0078)*exp(-((1-(z/(0.0078*D))^(-0.068))/0.068));
            zm = z_max*((1.1+7.0*(log(r./D)-0.2).^2)/(r./D));
            de = 2.1;
            gam = 0.35;
            eps = 0.06;
            kap = 0.18;
            ch = 1;
            c1 = -4.1;
            c2 = 1.1;
            ps_r = (de*r.^2./rm^2).^gam;
            sf = app.sfField.Value;

            % Individual parts of the functions
            ur_1 = exp(-(2*gam - (de.*r.^2./rm^2).^gam).^2) + eps .* exp(-kap.*(r.^2./rm^2));
            ur_2 = (z/D)^0.1 * exp(c1*(z/D)^1.1);
            wr_1 = (1 + 2.*gam.*ps_r.*(2*gam-ps_r)) .* exp(-(2*gam - ps_r).^2) + eps .* exp(-kap.*(r.^2./rm^2).^ch) .* (1 - kap.*ch.*(r./rm).^(2*ch));
            wr_2 = (D/-4.51) * (exp(-4.51*(z/D).^1.1)-1);

            % Plot radial profile horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, r, sf, ur_1, ur_2, wr_1, wr_2), 'k', r, compute_ur(app, r, sf, ur_1, ur_2), 'r', r, compute_wr(app, sf, wr_1, wr_2), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');            

%             % Adjust axis for radial profile
%             r_lim = 2 * r_max;
%             xlim(app.HorizontalAxes, [0, (r_lim+2000)]);

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update sliders to match field values
            if app.zmField.Value ~= app.zmSlider.Value
                app.zmSlider.Value = app.zmField.Value;
            end
            if app.JetDiameterField.Value ~= app.JetDiameterSlider.Value
                app.JetDiameterSlider.Value = app.JetDiameterField.Value;
            else
            end
            if app.sfField.Value ~= app.sfSlider.Value
                app.sfSlider.Value = app.sfField.Value;
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zSlider.Value = app.zField.Value;
            else 
            end

            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.zmField_2.Value = app.zmField.Value;
                app.zmSlider_2.Value = app.zmSlider.Value;
                app.JetDiameterField_2.Value = app.JetDiameterField.Value;
                app.JetDiameterSlider_2.Value = app.JetDiameterSlider.Value;
                app.sfField_2.Value = app.sfField.Value;
                app.sfSlider_2.Value = app.sfSlider.Value;

                % Define parameters for vertical profile
                z = 0:0.1:1000;
                D = app.JetDiameterField_2.Value * 1000;
                r = app.rField.Value * 1000;
                z_max = app.zmField_2.Value; 
                rm = 2.01*D - (z./0.0078).*exp(-((1-(z./(0.0078*D)).^(-0.068))/0.068));
                zm = z_max*((1.1+7.0*(log(r/D)-0.2)^2)/(r/D));
                ps_z = (2*r.^2./D^2).^0.35;
                gam = -227.69.*(z./D).^3+74.553.*(z./D).^2-5.0359.*(z./D)+0.4985;
                de = 2.1;
                eps = 25.466.*(z./D).^2-1.9908.*(z./D)+0.1593;
                kap = 12.938.*(z./D).^2+2.8563.*(z./D)+0.3019;
                ch = 125.38.*(z./D).^3-46.849.*(z./D).^2+0.9688.*(z./D)+0.9932;
                c1 = -0.16;
                c2 = 1.19;
                sf = app.sfField_2.Value;
    
                % Individual parts of the functions
                uz_1 = exp(-(0.7 - (2.*r.^2./D^2).^0.35).^2) + 0.18*exp(-0.06.*(r./D).^2);
                uz_2 = (z./zm).^(c2-1) .* exp(c1.*(z./zm).^c2);
                wz_1 = (1 + 0.7*ps_z*(0.7-ps_z)) * exp(-(0.7 - ps_z)^2) + 0.18*exp(-0.06*(r/D)^2) .* (1 - kap.*ch.*((r/D)^2));
                wz_2 = (zm/(c1*c2)) * (exp(c1.*(z./zm).^c2)-1);
    
                % Plot vertical profile horizontal and vertical velocities as well as magnitude
                plot(app.VerticalAxes, compute_w(app, r, sf, uz_1, uz_2, wz_1, wz_2), z, 'k', compute_uz(app, r, sf, uz_1, uz_2), z, 'r', compute_wz(app, sf, wz_1, wz_2), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: JetDiameterSlider, sfSlider, zSlider, 
        % ...and 1 other component
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:0.1:10000;
            D = app.JetDiameterSlider.Value * 1000;
            z = app.zSlider.Value;
            z_max = app.zmSlider.Value; 
            rm = 2.01*D - (z/0.0078)*exp(-((1-(z/(0.0078*D))^(-0.068))/0.068));
            zm = z_max*((1.1+7.0*(log(r./D)-0.2).^2)/(r./D));
            de = 2.1;
            gam = 0.35;
            eps = 0.06;
            kap = 0.18;
            ch = 1;
            c1 = -4.1;
            c2 = 1.1;
            ps_r = (de*r.^2./rm^2).^gam;
            sf = app.sfSlider.Value;

            % Individual parts of the functions
            ur_1 = exp(-(2*gam - (de.*r.^2./rm^2).^gam).^2) + eps .* exp(-kap.*(r.^2./rm^2));
            ur_2 = (z/D)^0.1 * exp(c1*(z/D)^1.1);
            wr_1 = (1 + 2.*gam.*ps_r.*(2*gam-ps_r)) .* exp(-(2*gam - ps_r).^2) + eps .* exp(-kap.*(r.^2./rm^2).^ch) .* (1 - kap.*ch.*(r./rm).^(2*ch));
            wr_2 = (D/-4.51) * (exp(-4.51*(z/D).^1.1)-1);

            % Plot radial profile horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, r, sf, ur_1, ur_2, wr_1, wr_2), 'k', r, compute_ur(app, r, sf, ur_1, ur_2), 'r', r, compute_wr(app, sf, wr_1, wr_2), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');            

%             % Adjust axis for radial profile
%             r_lim = 2 * r_max;
%             xlim(app.HorizontalAxes, [0, (r_lim+2000)]);

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update sliders to match field values
            if app.zmSlider.Value ~= app.zmField.Value
                app.zmField.Value = app.zmSlider.Value;
            end
            if app.JetDiameterSlider.Value ~= app.JetDiameterField.Value
                app.JetDiameterField.Value = app.JetDiameterSlider.Value;
            else
            end
            if app.sfSlider.Value ~= app.sfField.Value
                app.sfField.Value = app.sfSlider.Value;
            end
            if app.zSlider.Value ~= app.zField.Value
                app.zField.Value = app.zSlider.Value;
            else 
            end

            % Update field/slider values if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.zmField_2.Value = app.zmField.Value;
                app.zmSlider_2.Value = app.zmSlider.Value;
                app.JetDiameterField_2.Value = app.JetDiameterField.Value;
                app.JetDiameterSlider_2.Value = app.JetDiameterSlider.Value;
                app.sfField_2.Value = app.sfField.Value;
                app.sfSlider_2.Value = app.sfSlider.Value;
               
                % Define parameters for vertical profile
                z = 0:0.1:1000;
                D = app.JetDiameterSlider_2.Value * 1000;
                r = app.rSlider.Value * 1000;
                z_max = app.zmSlider_2.Value; 
                rm = 2.01*D - (z./0.0078).*exp(-((1-(z./(0.0078*D)).^(-0.068))/0.068));
                zm = z_max*((1.1+7.0*(log(r/D)-0.2)^2)/(r/D));
                ps_z = (2*r.^2./D^2).^0.35;
                gam = -227.69.*(z./D).^3+74.553.*(z./D).^2-5.0359.*(z./D)+0.4985;
                de = 2.1;
                eps = 25.466.*(z./D).^2-1.9908.*(z./D)+0.1593;
                kap = 12.938.*(z./D).^2+2.8563.*(z./D)+0.3019;
                ch = 125.38.*(z./D).^3-46.849.*(z./D).^2+0.9688.*(z./D)+0.9932;
                c1 = -0.16;
                c2 = 1.19;
                sf = app.sfSlider_2.Value;
    
                % Individual parts of the functions
                uz_1 = exp(-(0.7 - (2.*r.^2./D^2).^0.35).^2) + 0.18*exp(-0.06.*(r./D).^2);
                uz_2 = (z./zm).^(c2-1) .* exp(c1.*(z./zm).^c2);
                wz_1 = (1 + 0.7*ps_z*(0.7-ps_z)) * exp(-(0.7 - ps_z)^2) + 0.18*exp(-0.06*(r/D)^2) .* (1 - kap.*ch.*((r/D)^2));
                wz_2 = (zm/(c1*c2)) * (exp(c1.*(z./zm).^c2)-1);
    
                % Plot vertical profile horizontal and vertical velocities as well as magnitude
                plot(app.VerticalAxes, compute_w(app, r, sf, uz_1, uz_2, wz_1, wz_2), z, 'k', compute_uz(app, r, sf, uz_1, uz_2), z, 'r', compute_wz(app, sf, wz_1, wz_2), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: JetDiameterField_2, rField, sfField_2, 
        % ...and 1 other component
        function FieldValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:0.1:1000;
            D = app.JetDiameterField_2.Value * 1000;
            r = app.rField.Value * 1000;
            z_max = app.zmField_2.Value; 
            rm = 2.01*D - (z./0.0078).*exp(-((1-(z./(0.0078*D)).^(-0.068))/0.068));
            zm = z_max*((1.1+7.0*(log(r/D)-0.2)^2)/(r/D));
            ps_z = (2*r.^2./D^2).^0.35;
            gam = -227.69.*(z./D).^3+74.553.*(z./D).^2-5.0359.*(z./D)+0.4985;
            de = 2.1;
            eps = 25.466.*(z./D).^2-1.9908.*(z./D)+0.1593;
            kap = 12.938.*(z./D).^2+2.8563.*(z./D)+0.3019;
            ch = 125.38.*(z./D).^3-46.849.*(z./D).^2+0.9688.*(z./D)+0.9932;
            c1 = -0.16;
            c2 = 1.19;
            sf = app.sfField_2.Value;

            % Individual parts of the functions
            uz_1 = exp(-(0.7 - (2.*r.^2./D^2).^0.35).^2) + 0.18*exp(-0.06.*(r./D).^2);
            uz_2 = (z./zm).^(c2-1) .* exp(c1.*(z./zm).^c2);
            wz_1 = (1 + 0.7*ps_z*(0.7-ps_z)) * exp(-(0.7 - ps_z)^2) + 0.18*exp(-0.06*(r/D)^2) .* (1 - kap.*ch.*((r/D)^2));
            wz_2 = (zm/(c1*c2)) * (exp(c1.*(z./zm).^c2)-1);

            % Plot vertical profile horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_w(app, r, sf, uz_1, uz_2, wz_1, wz_2), z, 'k', compute_uz(app, r, sf, uz_1, uz_2), z, 'r', compute_wz(app, sf, wz_1, wz_2), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.zmField_2.Value ~= app.zmSlider_2.Value
                app.zmSlider_2.Value = app.zmField_2.Value;
            end
            if app.JetDiameterField_2.Value ~= app.JetDiameterSlider_2.Value
                app.JetDiameterSlider_2.Value = app.JetDiameterField_2.Value;
            else
            end
            if app.sfField_2.Value ~= app.sfSlider_2.Value
                app.sfSlider_2.Value = app.sfField_2.Value;
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rSlider.Value = app.rField.Value;
            else
            end
            
            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.zmField.Value = app.zmField_2.Value;
                app.zmSlider.Value = app.zmSlider_2.Value;
                app.JetDiameterField.Value = app.JetDiameterField_2.Value;
                app.JetDiameterSlider.Value = app.JetDiameterSlider_2.Value;
                app.sfField.Value = app.sfField_2.Value;
                app.sfSlider.Value = app.sfSlider_2.Value;

                % Define parameters for radial profile
                r = 0:0.1:10000;
                D = app.JetDiameterField.Value * 1000;
                z = app.zField.Value;
                z_max = app.zmField.Value; 
                rm = 2.01*D - (z/0.0078)*exp(-((1-(z/(0.0078*D))^(-0.068))/0.068));
                zm = z_max*((1.1+7.0*(log(r./D)-0.2).^2)/(r./D));
                de = 2.1;
                gam = 0.35;
                eps = 0.06;
                kap = 0.18;
                ch = 1;
                c1 = -4.1;
                c2 = 1.1;
                ps_r = (de*r.^2./rm^2).^gam;
                sf = app.sfField.Value;
        
                % Individual parts of the functions
                ur_1 = exp(-(2*gam - (de.*r.^2./rm^2).^gam).^2) + eps .* exp(-kap.*(r.^2./rm^2));
                ur_2 = (z/D)^0.1 * exp(c1*(z/D)^1.1);
                wr_1 = (1 + 2.*gam.*ps_r.*(2*gam-ps_r)) .* exp(-(2*gam - ps_r).^2) + eps .* exp(-kap.*(r.^2./rm^2).^ch) .* (1 - kap.*ch.*(r./rm).^(2*ch));
                wr_2 = (D/-4.51) * (exp(-4.51*(z/D).^1.1)-1);
        
                % Plot radial profile horizontal and vertical velocities as well as magnitude
                plot(app.HorizontalAxes, r, compute_u(app, r, sf, ur_1, ur_2, wr_1, wr_2), 'k', r, compute_ur(app, r, sf, ur_1, ur_2), 'r', r, compute_wr(app, sf, wr_1, wr_2), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.HorizontalAxes, 'Location', 'southoutside'); 

    %             % Adjust axis for radial profile
    %             r_lim = 2 * r_max;
    %             xlim(app.HorizontalAxes, [0, (r_lim+2000)]);
    
                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end    
            else
            end
        end

        % Value changed function: JetDiameterSlider_2, rSlider, 
        % ...and 2 other components
        function SliderValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:0.1:1000;
            D = app.JetDiameterSlider_2.Value * 1000;
            r = app.rSlider.Value * 1000;
            z_max = app.zmSlider_2.Value; 
            rm = 2.01*D - (z./0.0078).*exp(-((1-(z./(0.0078*D)).^(-0.068))/0.068));
            zm = z_max*((1.1+7.0*(log(r/D)-0.2)^2)/(r/D));
            ps_z = (2*r.^2./D^2).^0.35;
            gam = -227.69.*(z./D).^3+74.553.*(z./D).^2-5.0359.*(z./D)+0.4985;
            de = 2.1;
            eps = 25.466.*(z./D).^2-1.9908.*(z./D)+0.1593;
            kap = 12.938.*(z./D).^2+2.8563.*(z./D)+0.3019;
            ch = 125.38.*(z./D).^3-46.849.*(z./D).^2+0.9688.*(z./D)+0.9932;
            c1 = -0.16;
            c2 = 1.19;
            sf = app.sfSlider_2.Value;

            % Individual parts of the functions
            uz_1 = exp(-(0.7 - (2.*r.^2./D^2).^0.35).^2) + 0.18*exp(-0.06.*(r./D).^2);
            uz_2 = (z./zm).^(c2-1) .* exp(c1.*(z./zm).^c2);
            wz_1 = (1 + 0.7*ps_z*(0.7-ps_z)) * exp(-(0.7 - ps_z)^2) + 0.18*exp(-0.06*(r/D)^2) .* (1 - kap.*ch.*((r/D)^2));
            wz_2 = (zm/(c1*c2)) * (exp(c1.*(z./zm).^c2)-1);

            % Plot vertical profile horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_w(app, r, sf, uz_1, uz_2, wz_1, wz_2), z, 'k', compute_uz(app, r, sf, uz_1, uz_2), z, 'r', compute_wz(app, sf, wz_1, wz_2), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end  

            % Update field values to match sliders
            if app.zmSlider_2.Value ~= app.zmField_2.Value
                app.zmField_2.Value = app.zmSlider_2.Value;
            end
            if app.JetDiameterSlider_2.Value ~= app.JetDiameterField_2.Value
                app.JetDiameterField_2.Value = app.JetDiameterSlider_2.Value;
            else
            end
            if app.sfSlider_2.Value ~= app.sfField_2.Value
                app.sfSlider_2.Value = app.sfField_2.Value;
            end
            if app.rSlider.Value ~= app.rField.Value
                app.rField.Value = app.rSlider.Value;
            else
            end
            
            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.zmField.Value = app.zmField_2.Value;
                app.zmSlider.Value = app.zmSlider_2.Value;
                app.JetDiameterField.Value = app.JetDiameterField_2.Value;
                app.JetDiameterSlider.Value = app.JetDiameterSlider_2.Value;
                app.sfField.Value = app.sfField_2.Value;
                app.sfSlider.Value = app.sfSlider_2.Value;

                % Define parameters for radial profile
                r = 0:0.1:10000;
                D = app.JetDiameterField.Value * 1000;
                z = app.zField.Value;
                z_max = app.zmField.Value; 
                rm = 2.01*D - (z/0.0078)*exp(-((1-(z/(0.0078*D))^(-0.068))/0.068));
                zm = z_max*((1.1+7.0*(log(r./D)-0.2).^2)/(r./D));
                de = 2.1;
                gam = 0.35;
                eps = 0.06;
                kap = 0.18;
                ch = 1;
                c1 = -4.1;
                c2 = 1.1;
                ps_r = (de*r.^2./rm^2).^gam;
                sf = app.sfField.Value;
        
                % Individual parts of the functions
                ur_1 = exp(-(2*gam - (de.*r.^2./rm^2).^gam).^2) + eps .* exp(-kap.*(r.^2./rm^2));
                ur_2 = (z/D)^0.1 * exp(c1*(z/D)^1.1);
                wr_1 = (1 + 2.*gam.*ps_r.*(2*gam-ps_r)) .* exp(-(2*gam - ps_r).^2) + eps .* exp(-kap.*(r.^2./rm^2).^ch) .* (1 - kap.*ch.*(r./rm).^(2*ch));
                wr_2 = (D/-4.51) * (exp(-4.51*(z/D).^1.1)-1);
        
                % Plot radial profile horizontal and vertical velocities as well as magnitude
                plot(app.HorizontalAxes, r, compute_u(app, r, sf, ur_1, ur_2, wr_1, wr_2), 'k', r, compute_ur(app, r, sf, ur_1, ur_2), 'r', r, compute_wr(app, sf, wr_1, wr_2), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.HorizontalAxes, 'Location', 'southoutside');             
              
    %             % Adjust axis for radial profile
    %             r_lim = 2 * r_max;
    %             xlim(app.HorizontalAxes, [0, (r_lim+2000)]);

                % "Plotting options" menu check box adjustments
                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end    
            else
            end
        end

        % Value changed function: PlothorizontalvelocityCheckBox, 
        % ...and 2 other components
        function CheckBoxValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:0.1:10000;
            D = app.JetDiameterField.Value * 1000;
            z = app.zField.Value;
            z_max = app.zmField.Value; 
            rm = 2.01*D - (z/0.0078)*exp(-((1-(z/(0.0078*D))^(-0.068))/0.068));
            zm = z_max*((1.1+7.0*(log(r./D)-0.2).^2)/(r./D));
            de = 2.1;
            gam = 0.35;
            eps = 0.06;
            kap = 0.18;
            ch = 1;
            c1 = -4.1;
            c2 = 1.1;
            ps_r = (de*r.^2./rm^2).^gam;
            sf = app.sfField.Value;

            % Individual parts of the functions
            ur_1 = exp(-(2*gam - (de.*r.^2./rm^2).^gam).^2) + eps .* exp(-kap.*(r.^2./rm^2));
            ur_2 = (z/D)^0.1 * exp(c1*(z/D)^1.1);
            wr_1 = (1 + 2.*gam.*ps_r.*(2*gam-ps_r)) .* exp(-(2*gam - ps_r).^2) + eps .* exp(-kap.*(r.^2./rm^2).^ch) .* (1 - kap.*ch.*(r./rm).^(2*ch));
            wr_2 = (D/-4.51) * (exp(-4.51*(z/D).^1.1)-1);

            % Plot radial profile horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u(app, r, sf, ur_1, ur_2, wr_1, wr_2), 'k', r, compute_ur(app, r, sf, ur_1, ur_2), 'r', r, compute_wr(app, sf, wr_1, wr_2), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');            

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
            

            % Define parameters for vertical profile
            z = 0:0.1:1000;
            D = app.JetDiameterField_2.Value * 1000;
            r = app.rField.Value * 1000;
            z_max = app.zmField_2.Value; 
            rm = 2.01*D - (z./0.0078).*exp(-((1-(z./(0.0078*D)).^(-0.068))/0.068));
            zm = z_max*((1.1+7.0*(log(r/D)-0.2)^2)/(r/D));
            ps_z = (2*r.^2./D^2).^0.35;
            gam = -227.69.*(z./D).^3+74.553.*(z./D).^2-5.0359.*(z./D)+0.4985;
            de = 2.1;
            eps = 25.466.*(z./D).^2-1.9908.*(z./D)+0.1593;
            kap = 12.938.*(z./D).^2+2.8563.*(z./D)+0.3019;
            ch = 125.38.*(z./D).^3-46.849.*(z./D).^2+0.9688.*(z./D)+0.9932;
            c1 = -0.16;
            c2 = 1.19;
            sf = app.sfField_2.Value;

            % Individual parts of the functions
            uz_1 = exp(-(0.7 - (2.*r.^2./D^2).^0.35).^2) + 0.18*exp(-0.06.*(r./D).^2);
            uz_2 = (z./zm).^(c2-1) .* exp(c1.*(z./zm).^c2);
            wz_1 = (1 + 0.7*ps_z*(0.7-ps_z)) * exp(-(0.7 - ps_z)^2) + 0.18*exp(-0.06*(r/D)^2) .* (1 - kap.*ch.*((r/D)^2));
            wz_2 = (zm/(c1*c2)) * (exp(c1.*(z./zm).^c2)-1);

            % Plot vertical profile horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_w(app, r, sf, uz_1, uz_2, wz_1, wz_2), z, 'k', compute_uz(app, r, sf, uz_1, uz_2), z, 'r', compute_wz(app, sf, wz_1, wz_2), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            r = 0.1:0.1:10000;
            D = app.JetDiameterField.Value * 1000;
            z = app.zField.Value;
            z_max = app.zmField.Value; 
            rm = 2.01*D - (z/0.0078)*exp(-((1-(z/(0.0078*D))^(-0.068))/0.068));
            zm = z_max*((1.1+7.0*(log(r./D)-0.2).^2)/(r./D));
            de = 2.1;
            gam = 0.35;
            eps = 0.06;
            kap = 0.18;
            ch = 1;
            c1 = -4.1;
            c2 = 1.1;
            ps_r = (de*r.^2./rm^2).^gam;
            sf = app.sfField.Value;

            % Individual parts of the functions
            ur_1 = exp(-(2*gam - (de.*r.^2./rm^2).^gam).^2) + eps .* exp(-kap.*(r.^2./rm^2));
            ur_2 = (z/D)^0.1 * exp(c1*(z/D)^1.1);
            wr_1 = (1 + 2.*gam.*ps_r.*(2*gam-ps_r)) .* exp(-(2*gam - ps_r).^2) + eps .* exp(-kap.*(r.^2./rm^2).^ch) .* (1 - kap.*ch.*(r./rm).^(2*ch));
            wr_2 = (D/-4.51) * (exp(-4.51*(z/D).^1.1)-1);

            % Put x axis values into an array
            r = [0.1:0.1:10000];
            % Compute velocities and insert into arrays
            m = [compute_u(app, r, sf, ur_1, ur_2, wr_1, wr_2)];
            h = [compute_ur(app, r, sf, ur_1, ur_2)];
            v = [compute_wr(app, sf, wr_1, wr_2)];

            % Arrange arrays into a table
            ar = [r; m; h; v];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2", "Var3", "Var4"], ["Distance from downdraft center [m]","Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButton_2Pushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.VerticalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.VerticalAxes.XLim;
            figAxes.YLim = app.VerticalAxes.YLim;
            figAxes.ZLim = app.VerticalAxes.ZLim;
            figAxes.DataAspectRatio = app.VerticalAxes.DataAspectRatio;
            xlabel(figAxes, 'Velocity [m/s]');
            ylabel(figAxes, 'Height above ground [m]');
            title(figAxes, 'Vertical Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButton_2Pushed(app, event)
            % Define parameters for vertical profile
            z = 0:0.1:1000;
            D = app.JetDiameterField_2.Value * 1000;
            r = app.rField.Value * 1000;
            z_max = app.zmField_2.Value; 
            rm = 2.01*D - (z./0.0078).*exp(-((1-(z./(0.0078*D)).^(-0.068))/0.068));
            zm = z_max*((1.1+7.0*(log(r/D)-0.2)^2)/(r/D));
            ps_z = (2*r.^2./D^2).^0.35;
            gam = -227.69.*(z./D).^3+74.553.*(z./D).^2-5.0359.*(z./D)+0.4985;
            de = 2.1;
            eps = 25.466.*(z./D).^2-1.9908.*(z./D)+0.1593;
            kap = 12.938.*(z./D).^2+2.8563.*(z./D)+0.3019;
            ch = 125.38.*(z./D).^3-46.849.*(z./D).^2+0.9688.*(z./D)+0.9932;
            c1 = -0.16;
            c2 = 1.19;
            sf = app.sfField_2.Value;

            % Individual parts of the functions
            uz_1 = exp(-(0.7 - (2.*r.^2./D^2).^0.35).^2) + 0.18*exp(-0.06.*(r./D).^2);
            uz_2 = (z./zm).^(c2-1) .* exp(c1.*(z./zm).^c2);
            wz_1 = (1 + 0.7*ps_z*(0.7-ps_z)) * exp(-(0.7 - ps_z)^2) + 0.18*exp(-0.06*(r/D)^2) .* (1 - kap.*ch.*((r/D)^2));
            wz_2 = (zm/(c1*c2)) * (exp(c1.*(z./zm).^c2)-1);

            % Put y axis values into an array
            z = [0:0.1:1000];
            % Compute velocities and insert into arrays
            m = [compute_w(app, r, sf, uz_1, uz_2, wz_1, wz_2)];
            h = [compute_uz(app, r, sf, uz_1, uz_2)];
            v = [compute_wz(app, sf, wz_1, wz_2)];

            % Arrange arrays into a table
            ar = [m; h; v; z];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2", "Var3", "Var4"], ["Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]","Height above ground [m]"]);

            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Close request function: UIFigure
        function OBVAbdElaalCloseRequest(app, event)
            % Reenable OBV Abd Elaal button
            app.CallingApp.AbdElaalMillsMa2013Button.Enable = 'on';

            % Close OBV Abd Elaal window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @OBVAbdElaalCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.VerticalAxes = uiaxes(app.UIFigure);
            title(app.VerticalAxes, 'Vertical Profile of Velocity')
            xlabel(app.VerticalAxes, 'Velocity [m/s]')
            ylabel(app.VerticalAxes, 'Height above ground [m]')
            zlabel(app.VerticalAxes, 'Z')
            app.AbdElaalMillsMa2013Label = uilabel(app.UIFigure);
            app.AbdElaalMillsMa2013Label.Text = 'Abd-Elaal, Mills, & Ma (2013)';
            app.RadialProfileModelParametersPanel = uipanel(app.UIFigure);
            app.RadialProfileModelParametersPanel.Title = 'Radial Profile: Model Parameters';
            app.JetDiameterkmLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.JetDiameterkmLabel.Text = 'Jet Diameter [km]';
            app.JetDiameterSlider = uislider(app.RadialProfileModelParametersPanel);
            app.JetDiameterSlider.Limits = [0 10];
            app.JetDiameterSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.JetDiameterSlider.Value = 4;
            app.JetDiameterField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.JetDiameterField.Limits = [0 10];
            app.JetDiameterField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.JetDiameterField.Value = 4;
            app.ScalingfactorLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.ScalingfactorLabel.Text = 'Scaling factor, λ';
            app.sfSlider = uislider(app.RadialProfileModelParametersPanel);
            app.sfSlider.Limits = [0 0.2];
            app.sfSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.sfSlider.Value = 0.04;
            app.sfField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.sfField.Limits = [0 0.2];
            app.sfField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.sfField.Value = 0.04;
            app.HeightofmaximumhorizontalvelocitymLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.HeightofmaximumhorizontalvelocitymLabel.Text = 'Height of maximum horizontal velocity [m]';
            app.zmSlider = uislider(app.RadialProfileModelParametersPanel);
            app.zmSlider.Limits = [0 1000];
            app.zmSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zmSlider.Value = 100;
            app.zmField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.zmField.Limits = [0 1000];
            app.zmField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.zmField.Value = 100;
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = {'Export Plot'; ''};
            app.ExportDataTableButton = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton.Text = {'Export Data Table'; ''};
            app.VerticalProfileModelParametersPanel = uipanel(app.UIFigure);
            app.VerticalProfileModelParametersPanel.Title = 'Vertical Profile: Model Parameters';
            app.JetDiameterkmLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.JetDiameterkmLabel_2.Text = 'Jet Diameter [km]';
            app.JetDiameterSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.JetDiameterSlider_2.Limits = [0 10];
            app.JetDiameterSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.JetDiameterSlider_2.Value = 4;
            app.JetDiameterField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.JetDiameterField_2.Limits = [0 10];
            app.JetDiameterField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.JetDiameterField_2.Value = 4;
            app.HeightofmaximumhorizontalvelocitymLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.HeightofmaximumhorizontalvelocitymLabel_2.Text = 'Height of maximum horizontal velocity [m]';
            app.zmSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.zmSlider_2.Limits = [0 1000];
            app.zmSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.zmSlider_2.Value = 100;
            app.zmField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.zmField_2.Limits = [0 1000];
            app.zmField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.zmField_2.Value = 100;
            app.ScalingfactorLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.ScalingfactorLabel_2.Text = 'Scaling factor, λ';
            app.sfSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.sfSlider_2.Limits = [0 0.2];
            app.sfSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.sfSlider_2.Value = 0.04;
            app.sfField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.sfField_2.Limits = [0 0.2];
            app.sfField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.sfField_2.Value = 0.04;
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_2Pushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButton_2Pushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.Panel_2 = uipanel(app.UIFigure);
            app.zmLabel_2 = uilabel(app.Panel_2);
            app.zmLabel_2.Text = 'Distance from downdraft center [km]';
            app.rField = uieditfield(app.Panel_2, 'numeric');
            app.rField.Limits = [0 10];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.rField.Value = 5;
            app.rSlider = uislider(app.Panel_2);
            app.rSlider.Limits = [0 10];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.rSlider.Value = 5;
            app.Panel = uipanel(app.UIFigure);
            app.zmLabel = uilabel(app.Panel);
            app.zmLabel.Text = 'Height above ground [m]';
            app.zSlider = uislider(app.Panel);
            app.zSlider.Limits = [0 1000];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zSlider.Value = 100;
            app.zField = uieditfield(app.Panel, 'numeric');
            app.zField.Limits = [0 1000];
            app.zField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.zField.Value = 100;
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotmagnitudeCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmagnitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotmagnitudeCheckBox.Text = 'Plot magnitude';
            app.PlothorizontalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlothorizontalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlothorizontalvelocityCheckBox.Text = 'Plot horizontal velocity';
            app.PlotverticalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotverticalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotverticalvelocityCheckBox.Text = 'Plot vertical velocity';
            app.Panel_3 = uipanel(app.UIFigure);
            app.FixedParametersCheckBox = uicheckbox(app.Panel_3);
            app.FixedParametersCheckBox.Text = 'Keep model parameters fixed';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://doi.org/10.1016/j.jweia.2013.01.005';
            app.Hyperlink.Text = 'Corresponding Paper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OBV_AbdElaal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end