classdef Xhelaj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportDataTableButton_3         matlab.ui.control.Button
        ExportPlotButton_3              matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        PlottingOptionsPanel            matlab.ui.container.Panel
        PlotmeridionalvelocityCheckBox  matlab.ui.control.CheckBox
        PlotzonalvelocityCheckBox       matlab.ui.control.CheckBox
        PlotmagnitudeCheckBox           matlab.ui.control.CheckBox
        ExportDataTableButton_2         matlab.ui.control.Button
        ModelParametersPanel            matlab.ui.container.Panel
        ySlider                         matlab.ui.control.Slider
        InitialstormlocationalongyaxiskmLabel  matlab.ui.control.Label
        yField                          matlab.ui.control.NumericEditField
        xSlider                         matlab.ui.control.Slider
        InitialstormlocatioinalongxaxiskmLabel  matlab.ui.control.Label
        xField                          matlab.ui.control.NumericEditField
        MaxoutflowvelField              matlab.ui.control.NumericEditField
        MaxoutflowvelSlider             matlab.ui.control.Slider
        MaximumoutflowvelocitymsLabel   matlab.ui.control.Label
        MaxrSlider                      matlab.ui.control.Slider
        RadiusofmaximumoutflowvelocitykmLabel  matlab.ui.control.Label
        MaxrField                       matlab.ui.control.NumericEditField
        TvelSlider                      matlab.ui.control.Slider
        TranslationalvelocitymsLabel    matlab.ui.control.Label
        TvelField                       matlab.ui.control.NumericEditField
        ABLvelSlider                    matlab.ui.control.Slider
        MeanABLwindvelocitymsLabel      matlab.ui.control.Label
        ABLvelField                     matlab.ui.control.NumericEditField
        TAngleSlider                    matlab.ui.control.Slider
        DirectionofstormtranslationdegreeLabel  matlab.ui.control.Label
        TAngleField                     matlab.ui.control.NumericEditField
        ABLAngleSlider                  matlab.ui.control.Slider
        DirectionofABLwinddegreeLabel   matlab.ui.control.Label
        ABLAngleField                   matlab.ui.control.NumericEditField
        AngleSlider                     matlab.ui.control.Slider
        AngleofdownburstobservationdegreeLabel  matlab.ui.control.Label
        AngleField                      matlab.ui.control.NumericEditField
        TotaltimeField                  matlab.ui.control.NumericEditField
        TotaltimeSlider                 matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_3  matlab.ui.control.Label
        rSlider                         matlab.ui.control.Slider
        rField                          matlab.ui.control.NumericEditField
        TimeofstormSliderLabel          matlab.ui.control.Label
        IntensificationtimeField        matlab.ui.control.NumericEditField
        IntensificationtimeSlider       matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel  matlab.ui.control.Label
        ExportPlotButton_2              matlab.ui.control.Button
        XhelajBurlandoSolari2020Label   matlab.ui.control.Label
        HorizontalAxes_2                matlab.ui.control.UIAxes
        HorizontalAxes                  matlab.ui.control.UIAxes
    end

   % Plot should be time vs. velocity
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)

        function u_x = compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)
            % Zonal component
            u_x = (v_r .* if_r .* cosd(br)) + (v_t .* cosd(bt) .* del .* if_t) + (v_b .* cosd(bb));
        end

        function u_y = compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)
            % Meridional component
            u_y = (v_r .* if_r .* sind(br)) + (v_t .* sind(bt) .* del .* if_t) + (v_b .* sind(bb));
        end

        function u = compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)
            % Zonal component
            u_x = (v_r .* if_r .* cosd(br)) + (v_t .* cosd(bt) .* del .* if_t) + (v_b .* cosd(bb));
            % Meridional component 
            u_y = (v_r .* if_r .* sind(br)) + (v_t .* sind(bt) .* del .* if_t) + (v_b .* sind(bb));
            % Magnitude
            u = sqrt(u_x.^2 + u_y.^2);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller10)
            % Store selection menu app object
            app.CallingApp = caller10;
            
            % Set up plotting options menu
            app.PlotmagnitudeCheckBox.Value = 1;
            app.PlotzonalvelocityCheckBox.Value = 1;
            app.PlotmeridionalvelocityCheckBox.Value = 1;

            % Define parameters for time profile
            r_max = app.MaxrSlider.Value * 1000;
            ur_max = app.MaxoutflowvelSlider.Value;
            x0 = app.xSlider.Value * 1000;
            y0 = app.ySlider.Value * 1000;
            v_t = app.TvelSlider.Value;
            bt = app.TAngleSlider.Value;
            v_b = app.ABLvelSlider.Value;
            bb = app.ABLAngleSlider.Value;
            t_int = app.IntensificationtimeSlider.Value;
            t_f = app.TotaltimeSlider.Value;
            t = 0:0.1:t_f;
            if_r = zeros(1, length(t));
            for i = 1:length(t)
                if t(i) <= t_int
                    if_r(i) = t(i) / t_int;
                else 
                    if_r(i) = exp((-(t(i)-t_int))/((t_f - t_int)/log(10)));   
                end
            end
            t_intr = 0.2 * t_int;
            t_fmax = 1.2 * t_f;
            if_t = zeros(1, length(t));
            for i = 1:length(t)
                if (0 <= t(i) && t(i) <= t_intr)
                    if_t(i) = 0.5 * (1 + cos((pi/t_intr)*(t(i)-t_intr)));
                elseif (t_intr < t(i) && t(i) <= t_f)
                    if_t(i) = 1;
                elseif (t_f < t(i) && t(i) <= t_fmax)
                    if_t(i) = 0.5 * (1 + cos((pi/(0.2*t_f))*(t(i)-t_f)));
                elseif t(i) > t_fmax
                    if_t(i) = 0;
                end
            end
            % r and v_r is for time plot
            r = app.rSlider.Value * 1000;
            if r <= r_max
                v_r = ur_max * (r/r_max);
            else
                v_r = ur_max * exp(-((r - r_max)/(0.5*r_max))^2);
            end
            r_e = 2 * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            x_ct = zeros(1, length(t));
            for i = 1:length(t)
                x_ct(i) = x0 + (v_t * t(i) * cosd(bt));
            end
            y_ct = zeros(1, length(t));
            for i = 1:length(t)
                y_ct(i) = y0 + (v_t * t(i) * cosd(bt));
            end
            br = zeros(1, length(t));
            for i = 1:length(t)
                br = atan2d(y_ct(i), x_ct(i));
            end
            xp = 0;
            yp = 0;
            rt = zeros(1, length(t));
            for i = 1:length(t)
                rt(i) = sqrt((xp - x_ct(i))^2 + (yp - y_ct(i))^2);
            end
            del = zeros(1, length(t));
            for i = 1:length(t)
                if (0 <= rt(i) && rt(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < rt(i) && rt(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(rt(i)-r_e)));
                elseif rt(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot time profile
            plot(app.HorizontalAxes, t, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', t, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', t, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Zonal Velocity', 'Meridional Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Define parameters for radial profile 
            % rr and v_rr are for radial plot
            rr = 0:0.1:10000;
            v_rr = zeros(1, length(rr));
            for i = 1:length(rr)
                if rr(i) <= r_max
                    v_rr(i) = ur_max * (rr(i)/r_max);
                else
                    v_rr(i) = ur_max * exp(-((rr(i) - r_max)/(0.5*r_max))^2);
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes_2, rr, v_rr, 'k', 'LineWidth', 1.5)
        end

        % Value changed function: ABLAngleSlider, ABLvelSlider, 
        % ...and 10 other components
        function SliderValueChanged(app, event)
            % Define parameters for time profile
            r_max = app.MaxrSlider.Value * 1000;
            ur_max = app.MaxoutflowvelSlider.Value;
            x0 = app.xSlider.Value * 1000;
            y0 = app.ySlider.Value * 1000;
            v_t = app.TvelSlider.Value;
            bt = app.TAngleSlider.Value;
            v_b = app.ABLvelSlider.Value;
            bb = app.ABLAngleSlider.Value;
            t_int = app.IntensificationtimeSlider.Value;
            t_f = app.TotaltimeSlider.Value;
            t = 0:0.1:t_f;
            if_r = zeros(1, length(t));
            for i = 1:length(t)
                if t(i) <= t_int
                    if_r(i) = t(i) / t_int;
                else 
                    if_r(i) = exp((-(t(i)-t_int))/((t_f - t_int)/log(10)));   
                end
            end
            t_intr = 0.2 * t_int;
            t_fmax = 1.2 * t_f;
            if_t = zeros(1, length(t));
            for i = 1:length(t)
                if (0 <= t(i) && t(i) <= t_intr)
                    if_t(i) = 0.5 * (1 + cos((pi/t_intr)*(t(i)-t_intr)));
                elseif (t_intr < t(i) && t(i) <= t_f)
                    if_t(i) = 1;
                elseif (t_f < t(i) && t(i) <= t_fmax)
                    if_t(i) = 0.5 * (1 + cos((pi/(0.2*t_f))*(t(i)-t_f)));
                elseif t(i) > t_fmax
                    if_t(i) = 0;
                end
            end
            r = app.rSlider.Value * 1000;
            if r <= r_max
                v_r = ur_max * (r/r_max);
            else
                v_r = ur_max * exp(-((r - r_max)/(0.5*r_max))^2);
            end
            r_e = 2 * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            x_ct = zeros(1, length(t));
            for i = 1:length(t)
                x_ct(i) = x0 + (v_t * t(i) * cosd(bt));
            end
            y_ct = zeros(1, length(t));
            for i = 1:length(t)
                y_ct(i) = y0 + (v_t * t(i) * cosd(bt));
            end
            br = zeros(1, length(t));
            for i = 1:length(t)
                br = atan2d(y_ct(i), x_ct(i));
            end
            xp = 0;
            yp = 0;
            rt = zeros(1, length(t));
            for i = 1:length(t)
                rt(i) = sqrt((xp - x_ct(i))^2 + (yp - y_ct(i))^2);
            end
            del = zeros(1, length(t));
            for i = 1:length(t)
                if (0 <= rt(i) && rt(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < rt(i) && rt(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(rt(i)-r_e)));
                elseif rt(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot time profile
            plot(app.HorizontalAxes, t, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', t, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', t, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Zonal Velocity', 'Meridional Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlotzonalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotmeridionalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Define parameters for radial profile 
            % rr and v_rr are for radial plot
            rr = 0:0.1:10000;
            v_rr = zeros(1, length(rr));
            for i = 1:length(rr)
                if rr(i) <= r_max
                    v_rr(i) = ur_max * (rr(i)/r_max);
                else
                    v_rr(i) = ur_max * exp(-((rr(i) - r_max)/(0.5*r_max))^2);
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes_2, rr, v_rr, 'k', 'LineWidth', 1.5)
            % Update field values to match sliders
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelField.Value = app.MaxoutflowvelSlider.Value;
            else
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrField.Value = app.MaxrSlider.Value;
            else
            end
            if app.AngleField.Value ~= app.AngleSlider.Value
                app.AngleField.Value = app.AngleSlider.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeField.Value = app.IntensificationtimeSlider.Value;
            else
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rField.Value = app.rSlider.Value;
            else
            end
            if app.TotaltimeField.Value ~= app.TotaltimeSlider.Value
                app.TotaltimeField.Value = app.TotaltimeSlider.Value;
            else
            end
            if app.TAngleField.Value ~= app.TAngleSlider.Value
                app.TAngleField.Value = app.TAngleSlider.Value;
            else
            end
            if app.ABLAngleField.Value ~= app.ABLAngleSlider.Value
                app.ABLAngleField.Value = app.ABLAngleSlider.Value;
            else
            end
            if app.TvelField.Value ~= app.TvelSlider.Value
                app.TvelField.Value = app.TvelSlider.Value;
            else
            end
            if app.ABLvelField.Value ~= app.ABLvelSlider.Value
                app.ABLvelField.Value = app.ABLvelSlider.Value;
            else
            end
            if app.xField.Value ~= app.xSlider.Value
                app.xField.Value = app.xSlider.Value;
            end
            if app.yField.Value ~= app.ySlider.Value
                app.yField.Value = app.ySlider.Value;
            end
        end

        % Value changed function: ABLAngleField, ABLvelField, AngleField, 
        % ...and 9 other components
        function FieldValueChanged(app, event)
            % Define parameters for time profile
            r_max = app.MaxrField.Value * 1000;
            ur_max = app.MaxoutflowvelField.Value;
            x0 = app.xField.Value * 1000;
            y0 = app.yField.Value * 1000;
            v_t = app.TvelField.Value;
            bt = app.TAngleField.Value;
            v_b = app.ABLvelField.Value;
            bb = app.ABLAngleField.Value;
            t_int = app.IntensificationtimeField.Value;
            t_f = app.TotaltimeField.Value;
            t = 0:0.1:t_f;
            if_r = zeros(1, length(t));
            for i = 1:length(t)
                if t(i) <= t_int
                    if_r(i) = t(i) / t_int;
                else 
                    if_r(i) = exp((-(t(i)-t_int))/((t_f - t_int)/log(10)));   
                end
            end
            t_intr = 0.2 * t_int;
            t_fmax = 1.2 * t_f;
            if_t = zeros(1, length(t));
            for i = 1:length(t)
                if (0 <= t(i) && t(i) <= t_intr)
                    if_t(i) = 0.5 * (1 + cos((pi/t_intr)*(t(i)-t_intr)));
                elseif (t_intr < t(i) && t(i) <= t_f)
                    if_t(i) = 1;
                elseif (t_f < t(i) && t(i) <= t_fmax)
                    if_t(i) = 0.5 * (1 + cos((pi/(0.2*t_f))*(t(i)-t_f)));
                elseif t(i) > t_fmax
                    if_t(i) = 0;
                end
            end
            r = app.rField.Value * 1000;
            if r <= r_max
                v_r = ur_max * (r/r_max);
            else
                v_r = ur_max * exp(-((r - r_max)/(0.5*r_max))^2);
            end
            r_e = 2 * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            x_ct = zeros(1, length(t));
            for i = 1:length(t)
                x_ct(i) = x0 + (v_t * t(i) * cosd(bt));
            end
            y_ct = zeros(1, length(t));
            for i = 1:length(t)
                y_ct(i) = y0 + (v_t * t(i) * cosd(bt));
            end
            br = zeros(1, length(t));
            for i = 1:length(t)
                br = atan2d(y_ct(i), x_ct(i));
            end
            xp = 0;
            yp = 0;
            rt = zeros(1, length(t));
            for i = 1:length(t)
                rt(i) = sqrt((xp - x_ct(i))^2 + (yp - y_ct(i))^2);
            end
            del = zeros(1, length(t));
            for i = 1:length(t)
                if (0 <= rt(i) && rt(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < rt(i) && rt(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(rt(i)-r_e)));
                elseif rt(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot time profile
            plot(app.HorizontalAxes, t, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', t, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', t, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Zonal Velocity', 'Meridional Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlotzonalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotmeridionalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
            
            % Define parameters for radial profile 
            % rr and v_rr are for radial plot
            rr = 0:0.1:10000;
            v_rr = zeros(1, length(rr));
            for i = 1:length(rr)
                if rr(i) <= r_max
                    v_rr(i) = ur_max * (rr(i)/r_max);
                else
                    v_rr(i) = ur_max * exp(-((rr(i) - r_max)/(0.5*r_max))^2);
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes_2, rr, v_rr, 'k', 'LineWidth', 1.5)

            % Update field values to match sliders
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelSlider.Value = app.MaxoutflowvelField.Value;
            else
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrSlider.Value = app.MaxrField.Value;
            else
            end
            if app.AngleField.Value ~= app.AngleSlider.Value
                app.AngleSlider.Value = app.AngleField.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeSlider.Value = app.IntensificationtimeField.Value;
            else
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rSlider.Value = app.rField.Value;
            else
            end
            if app.TotaltimeField.Value ~= app.TotaltimeSlider.Value
                app.TotaltimeSlider.Value = app.TotaltimeField.Value;
            else
            end
            if app.TAngleField.Value ~= app.TAngleSlider.Value
                app.TAngleSlider.Value = app.TAngleField.Value;
            else
            end
            if app.ABLAngleField.Value ~= app.ABLAngleSlider.Value
                app.ABLAngleSlider.Value = app.ABLAngleField.Value;
            else
            end
            if app.TvelField.Value ~= app.TvelSlider.Value
                app.TvelSlider.Value = app.TvelField.Value;
            else
            end
            if app.ABLvelField.Value ~= app.ABLvelSlider.Value
                app.ABLvelSlider.Value = app.ABLvelField.Value;
            else
            end
            if app.xField.Value ~= app.xSlider.Value
                app.xSlider.Value = app.xField.Value;
            end
            if app.yField.Value ~= app.ySlider.Value
                app.ySlider.Value = app.yField.Value;
            end
        end

        % Value changed function: PlotmagnitudeCheckBox, 
        % ...and 2 other components
        function CheckBoxValueChanged(app, event)
            % Define parameters for time profile
            r_max = app.MaxrField.Value * 1000;
            ur_max = app.MaxoutflowvelField.Value;
            x0 = app.xField.Value * 1000;
            y0 = app.yField.Value * 1000;
            v_t = app.TvelField.Value;
            bt = app.TAngleField.Value;
            v_b = app.ABLvelField.Value;
            bb = app.ABLAngleField.Value;
            t_int = app.IntensificationtimeField.Value;
            t_f = app.TotaltimeField.Value;
            t = 0:0.1:t_f;
            if_r = zeros(1, length(t));
            for i = 1:length(t)
                if t(i) <= t_int
                    if_r(i) = t(i) / t_int;
                else 
                    if_r(i) = exp((-(t(i)-t_int))/((t_f - t_int)/log(10)));   
                end
            end
            t_intr = 0.2 * t_int;
            t_fmax = 1.2 * t_f;
            if_t = zeros(1, length(t));
            for i = 1:length(t)
                if (0 <= t(i) && t(i) <= t_intr)
                    if_t(i) = 0.5 * (1 + cos((pi/t_intr)*(t(i)-t_intr)));
                elseif (t_intr < t(i) && t(i) <= t_f)
                    if_t(i) = 1;
                elseif (t_f < t(i) && t(i) <= t_fmax)
                    if_t(i) = 0.5 * (1 + cos((pi/(0.2*t_f))*(t(i)-t_f)));
                elseif t(i) > t_fmax
                    if_t(i) = 0;
                end
            end
            r = app.rField.Value * 1000;
            if r <= r_max
                v_r = ur_max * (r/r_max);
            else
                v_r = ur_max * exp(-((r - r_max)/(0.5*r_max))^2);
            end
            r_e = 2 * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            x_ct = zeros(1, length(t));
            for i = 1:length(t)
                x_ct(i) = x0 + (v_t * t(i) * cosd(bt));
            end
            y_ct = zeros(1, length(t));
            for i = 1:length(t)
                y_ct(i) = y0 + (v_t * t(i) * cosd(bt));
            end
            br = zeros(1, length(t));
            for i = 1:length(t)
                br = atan2d(y_ct(i), x_ct(i));
            end
            xp = 0;
            yp = 0;
            rt = zeros(1, length(t));
            for i = 1:length(t)
                rt(i) = sqrt((xp - x_ct(i))^2 + (yp - y_ct(i))^2);
            end
            del = zeros(1, length(t));
            for i = 1:length(t)
                if (0 <= rt(i) && rt(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < rt(i) && rt(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(rt(i)-r_e)));
                elseif rt(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot time profile
            plot(app.HorizontalAxes, t, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', t, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', t, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Zonal Velocity', 'Meridional Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlotzonalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotmeridionalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the time plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            xlabel(figAxes, 'Time [min]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Time Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end
            
            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            r_max = app.MaxrSlider.Value * 1000;
            ur_max = app.MaxoutflowvelSlider.Value;
            x0 = app.xSlider.Value * 1000;
            y0 = app.ySlider.Value * 1000;
            v_t = app.TvelSlider.Value;
            bt = app.TAngleSlider.Value;
            v_b = app.ABLvelSlider.Value;
            bb = app.ABLAngleSlider.Value;
            t_int = app.IntensificationtimeSlider.Value;
            t_f = app.TotaltimeSlider.Value;
            t = 0.1:0.1:t_f;
            if_r = zeros(1, length(t));
            for i = 1:length(t)
                if t(i) <= t_int
                    if_r(i) = t(i) / t_int;
                else 
                    if_r(i) = exp((-(t(i)-t_int))/((t_f - t_int)/log(10)));   
                end
            end
            t_intr = 0.2 * t_int;
            t_fmax = 1.2 * t_f;
            if_t = zeros(1, length(t));
            for i = 1:length(t)
                if (0 <= t(i) && t(i) <= t_intr)
                    if_t(i) = 0.5 * (1 + cos((pi/t_intr)*(t(i)-t_intr)));
                elseif (t_intr < t(i) && t(i) <= t_f)
                    if_t(i) = 1;
                elseif (t_f < t(i) && t(i) <= t_fmax)
                    if_t(i) = 0.5 * (1 + cos((pi/(0.2*t_f))*(t(i)-t_f)));
                elseif t(i) > t_fmax
                    if_t(i) = 0;
                end
            end
            r = app.rSlider.Value * 1000;
            if r <= r_max
                v_r = ur_max * (r/r_max);
            else
                v_r = ur_max * exp(-((r - r_max)/(0.5*r_max))^2);
            end
            r_e = 2 * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            x_ct = zeros(1, length(t));
            for i = 1:length(t)
                x_ct(i) = x0 + (v_t * t(i) * cosd(bt));
            end
            y_ct = zeros(1, length(t));
            for i = 1:length(t)
                y_ct(i) = y0 + (v_t * t(i) * cosd(bt));
            end
            br = zeros(1, length(t));
            for i = 1:length(t)
                br = atan2d(y_ct(i), x_ct(i));
            end
            xp = 0;
            yp = 0;
            rt = zeros(1, length(t));
            for i = 1:length(t)
                rt(i) = sqrt((xp - x_ct(i))^2 + (yp - y_ct(i))^2);
            end
            del = zeros(1, length(t));
            for i = 1:length(t)
                if (0 <= rt(i) && rt(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < rt(i) && rt(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(rt(i)-r_e)));
                elseif rt(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Put x axis values into an array
            t_2 = [0.1:0.1:t_f];
            % Compute velocities and insert into arrays
            m = [compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)];
            zon = [compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)];
            mer = [compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)];

            % Arrange arrays into a table
            ar = [t_2; m; zon; mer];
            tbl = array2table(ar.');
            tbl = renamevars(tbl, ["Var1", "Var2", "Var3", "Var4"], ["Time [min]","Magnitude [m/s]","Zonal Velocity [m/s]","Meridional Velocity [m/s]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(tbl, filenameFull)
            end
        end

        % Button pushed function: ExportDataTableButton_3
        function ExportDataTableButton_3Pushed(app, event)
            % Define parameters for radial profile 
            r_max = app.MaxrSlider.Value * 1000;
            ur_max = app.MaxoutflowvelSlider.Value;
            % rr and v_rr are for radial plot
            rr = 0.1:0.1:10000;
            v_rr = zeros(1, length(rr));
            for i = 1:length(rr)
                if rr(i) <= r_max
                    v_rr(i) = ur_max * (rr(i)/r_max);
                else
                    v_rr(i) = ur_max * exp(-((rr(i) - r_max)/(0.5*r_max))^2);
                end
            end

            % Create arrays
            r = [0.1:0.1:10000];
            v_r = [v_rr];

            % Arrange arrays into table 
            ar = [r; v_r];
            tbl = array2table(ar.');
            tbl = renamevars(tbl, ["Var1", "Var2"], ["Distance from downdraft center [m]", "Velocity [m/s]"]);

            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(tbl, filenameFull)
            end
        end

        % Button pushed function: ExportPlotButton_3
        function ExportPlotButton_3Pushed(app, event)
            % Create a temporary figure of the radial plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes_2.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes_2.XLim;
            figAxes.YLim = app.HorizontalAxes_2.YLim;
            figAxes.ZLim = app.HorizontalAxes_2.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes_2.DataAspectRatio;
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end
            
            % Delete the temporary figure
            delete(fig);
        end

        % Close request function: UIFigure
        function XhelajCloseRequest(app, event)
            % Reenable Xhelaj button
            app.CallingApp.XhelajBurlandoSolari2020Button.Enable = 'on';

            % Close Xhelaj window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @XhelajCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Time Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Time [min]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.HorizontalAxes_2 = uiaxes(app.UIFigure);
            title(app.HorizontalAxes_2, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes_2, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes_2, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes_2, 'Z')
            app.XhelajBurlandoSolari2020Label = uilabel(app.UIFigure);
            app.XhelajBurlandoSolari2020Label.Text = 'Xhelaj, Burlando, & Solari (2020)';
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ModelParametersPanel = uipanel(app.UIFigure);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.LengthofstormintensificationminSliderLabel = uilabel(app.ModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel.Text = 'Time length of storm intensification [min]';
            app.IntensificationtimeSlider = uislider(app.ModelParametersPanel);
            app.IntensificationtimeSlider.Limits = [0 15];
            app.IntensificationtimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.IntensificationtimeSlider.Value = 2;
            app.IntensificationtimeField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.IntensificationtimeField.Limits = [0 15];
            app.IntensificationtimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.IntensificationtimeField.Value = 2;
            app.TimeofstormSliderLabel = uilabel(app.ModelParametersPanel);
            app.TimeofstormSliderLabel.Text = 'Distance from downdraft center [km]';
            app.rField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.rField.Limits = [0 10];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.rField.Value = 1;
            app.rSlider = uislider(app.ModelParametersPanel);
            app.rSlider.Limits = [0 10];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rSlider.Value = 1;
            app.LengthofstormintensificationminSliderLabel_3 = uilabel(app.ModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_3.Text = 'Total time length of storm [min] ';
            app.TotaltimeSlider = uislider(app.ModelParametersPanel);
            app.TotaltimeSlider.Limits = [0 15];
            app.TotaltimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TotaltimeSlider.Value = 15;
            app.TotaltimeField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.TotaltimeField.Limits = [0 15];
            app.TotaltimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TotaltimeField.Value = 15;
            app.AngleField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.AngleField.Limits = [0 360];
            app.AngleField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.AngleField.Value = 90;
            app.AngleofdownburstobservationdegreeLabel = uilabel(app.ModelParametersPanel);
            app.AngleofdownburstobservationdegreeLabel.Text = 'Angle of downburst observation [degree]';
            app.AngleSlider = uislider(app.ModelParametersPanel);
            app.AngleSlider.Limits = [0 360];
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.AngleSlider.Value = 90;
            app.ABLAngleField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.ABLAngleField.Limits = [0 360];
            app.ABLAngleField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ABLAngleField.Value = 45;
            app.DirectionofABLwinddegreeLabel = uilabel(app.ModelParametersPanel);
            app.DirectionofABLwinddegreeLabel.Text = 'Direction of ABL wind [degree]';
            app.ABLAngleSlider = uislider(app.ModelParametersPanel);
            app.ABLAngleSlider.Limits = [0 360];
            app.ABLAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ABLAngleSlider.Value = 45;
            app.TAngleField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.TAngleField.Limits = [0 360];
            app.TAngleField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TAngleField.Value = 45;
            app.DirectionofstormtranslationdegreeLabel = uilabel(app.ModelParametersPanel);
            app.DirectionofstormtranslationdegreeLabel.Text = 'Direction of storm translation [degree]';
            app.TAngleSlider = uislider(app.ModelParametersPanel);
            app.TAngleSlider.Limits = [0 360];
            app.TAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TAngleSlider.Value = 45;
            app.ABLvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.ABLvelField.Limits = [0 30];
            app.ABLvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ABLvelField.Value = 12;
            app.MeanABLwindvelocitymsLabel = uilabel(app.ModelParametersPanel);
            app.MeanABLwindvelocitymsLabel.Text = 'Mean ABL wind velocity [m/s]';
            app.ABLvelSlider = uislider(app.ModelParametersPanel);
            app.ABLvelSlider.Limits = [0 30];
            app.ABLvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ABLvelSlider.Value = 12;
            app.TvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.TvelField.Limits = [0 30];
            app.TvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TvelField.Value = 10;
            app.TranslationalvelocitymsLabel = uilabel(app.ModelParametersPanel);
            app.TranslationalvelocitymsLabel.Text = 'Translational velocity [m/s]';
            app.TvelSlider = uislider(app.ModelParametersPanel);
            app.TvelSlider.Limits = [0 30];
            app.TvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TvelSlider.Value = 10;
            app.MaxrField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxrField.Limits = [0 10];
            app.MaxrField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxrField.Value = 1;
            app.RadiusofmaximumoutflowvelocitykmLabel = uilabel(app.ModelParametersPanel);
            app.RadiusofmaximumoutflowvelocitykmLabel.Text = 'Radius of maximum outflow velocity [km]';
            app.MaxrSlider = uislider(app.ModelParametersPanel);
            app.MaxrSlider.Limits = [0 10];
            app.MaxrSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxrSlider.Value = 1;
            app.MaximumoutflowvelocitymsLabel = uilabel(app.ModelParametersPanel);
            app.MaximumoutflowvelocitymsLabel.Text = 'Maximum outflow velocity [m/s]';
            app.MaxoutflowvelSlider = uislider(app.ModelParametersPanel);
            app.MaxoutflowvelSlider.Limits = [0 60];
            app.MaxoutflowvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxoutflowvelSlider.Value = 40;
            app.MaxoutflowvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxoutflowvelField.Limits = [0 60];
            app.MaxoutflowvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxoutflowvelField.Value = 40;
            app.xField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.xField.Limits = [-10 10];
            app.xField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.xField.Value = -3;
            app.InitialstormlocatioinalongxaxiskmLabel = uilabel(app.ModelParametersPanel);
            app.InitialstormlocatioinalongxaxiskmLabel.Text = 'Initial storm locatioin along x-axis [km]';
            app.xSlider = uislider(app.ModelParametersPanel);
            app.xSlider.Limits = [-10 10];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.xSlider.Value = -3;
            app.yField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.yField.Limits = [-10 10];
            app.yField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.yField.Value = -3;
            app.InitialstormlocationalongyaxiskmLabel = uilabel(app.ModelParametersPanel);
            app.InitialstormlocationalongyaxiskmLabel.Text = 'Initial storm location along y-axis [km]';
            app.ySlider = uislider(app.ModelParametersPanel);
            app.ySlider.Limits = [-10 10];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ySlider.Value = -3;
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotmagnitudeCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmagnitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotmagnitudeCheckBox.Text = 'Plot magnitude';
            app.PlotzonalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotzonalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotzonalvelocityCheckBox.Text = 'Plot zonal velocity';
            app.PlotmeridionalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmeridionalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotmeridionalvelocityCheckBox.Text = 'Plot meridional velocity';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://doi.org/10.1016/j.jweia.2020.104373';
            app.Hyperlink.Text = 'Corresponding Paper';
            app.ExportPlotButton_3 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_3Pushed, true);
            app.ExportPlotButton_3.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_3 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButton_3Pushed, true);
            app.ExportDataTableButton_3.Text = {'Export Data Table'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xhelaj(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end